classdef plotComplex3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        FineKnobLabel    matlab.ui.control.Label
        FineKnob         matlab.ui.control.Knob
        CoarseKnobLabel  matlab.ui.control.Label
        CoarseKnob       matlab.ui.control.Knob
    end

    
    properties (Access = private)
        baseValue = 0;
        coarseValue = 0;
        fineValue = 0;
    end
    
    methods (Access = private)
        
        function updateBaseValue(app)
            app.baseValue = app.coarseValue + app.fineValue;
            updatePlot(app);
        end
        
        function updateCoarseValue(app,value)
            app.coarseValue = value;
        end
        
        function updateFineValue(app,value)
            app.fineValue = value;
        end
        
        function updatePlot(app)
            t=0:0.05:49;
            s=power(app.baseValue,1i*t);
            app.UIAxes.Title.String = [num2str(app.baseValue) '^{-jt}'];
            plot3(app.UIAxes,real(s),t,imag(s));
            xlim(app.UIAxes,[-5 5]);
            ylim(app.UIAxes,[-20 20]);
            zlim(app.UIAxes,[-5 5]);
            app.UIAxes.XLabel.String = 'real';
            app.UIAxes.YLabel.String = 't';
            app.UIAxes.ZLabel.String = 'imag';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: CoarseKnob
        function CoarseKnobValueChanging(app, event)
            changingValue = event.Value;
            updateCoarseValue(app,changingValue);
            updateBaseValue(app);
        end

        % Value changing function: FineKnob
        function FineKnobValueChanging(app, event)
            changingValue = event.Value;
            updateFineValue(app,changingValue);
            updateBaseValue(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'real')
            ylabel(app.UIAxes, 't')
            app.CoarseKnobLabel = uilabel(app.UIFigure);
            app.CoarseKnobLabel.Text = 'Coarse';
            app.CoarseKnob = uiknob(app.UIFigure, 'continuous');
            app.CoarseKnob.Limits = [-5 5];
            app.CoarseKnob.ValueChangingFcn = createCallbackFcn(app, @CoarseKnobValueChanging, true);
            app.FineKnobLabel = uilabel(app.UIFigure);
            app.FineKnobLabel.Text = 'Fine';
            app.FineKnob = uiknob(app.UIFigure, 'continuous');
            app.FineKnob.Limits = [0 1];
            app.FineKnob.ValueChangingFcn = createCallbackFcn(app, @FineKnobValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotComplex3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end